classdef Series_Fouriel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        AddPlot_Button             matlab.ui.control.Button
        Harmonics_Spinner          matlab.ui.control.Spinner
        HarmonicsSpinnerLabel      matlab.ui.control.Label
        Especturm_Button           matlab.ui.control.Button
        Clear_Button               matlab.ui.control.Button
        End_EditField              matlab.ui.control.NumericEditField
        EndEditFieldLabel          matlab.ui.control.Label
        Stard_EditField            matlab.ui.control.NumericEditField
        StardEditFieldLabel        matlab.ui.control.Label
        IntegrationInterval_Label  matlab.ui.control.Label
        PlotFx_Button              matlab.ui.control.Button
        fx_EditField               matlab.ui.control.EditField
        fxEditFieldLabel           matlab.ui.control.Label
        FourielSeriesPlot_Label    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotFx_Button
        function PlotFx_ButtonPushed(app, event)
            
            % Graficamos la funcion
            
            % Funcion de entrada del usuario
            funcion = app.fx_EditField.Value;
            
            % Valores de integracion
            Start   = app.Stard_EditField.Value;
            End     = app.End_EditField.Value;
             
            % Graficamos la funcion            
            fplot(app.UIAxes,funcion,[Start End],'r-',"LineWidth",1);
            
        end

        % Button pushed function: Clear_Button
        function Clear_ButtonPushed(app, event)
            
            % Limpiamos la grafica
            cla(app.UIAxes);
            
        end

        % Button pushed function: AddPlot_Button
        function AddPlot_ButtonPushed(app, event)
            % Graficamos la funcion de la grafica
            
            % Funcion de entrada 
            funcion = app.fx_EditField.Value;
            
            % Valores de integracion
            Start   = app.Stard_EditField.Value;
            End     = app.End_EditField.Value;
            
            % Estado del numero de armonicos a evaluar en la funcion
            N       = app.Harmonics_Spinner.Value;
            
            % Intervalo de integracion 
            T       = End - Start;
            
            % Funcion real que reconoce el sistema
            F_R     = str2func(sprintf('@(x)%s',funcion));
            
            % Vector del numoero de armonicos (formula en la ilustracion 4)
            arms    = -N:N;
            
            % Coeficientes de integracion, vector (forumla en la ilustraccion 3)
            C_n = zeros(size(arms));
            
            % Condicional para verificar que no ingresen un numero diferente de 0 a 50            
            if N <= 0 & N >= 50
                
               app.Error_TextArea.Visible;               
                
            else 
                
                % calculamos el coeficiente de integracion (formula en la ilustracion 3)
                for vector      = 1:1:length(arms)
                    
                    n           = arms(vector);
                    
                    f_exp       = @(x) exp(-1i*2*pi*x*n/T);
                    
                    f_integral  = @(x) F_R(x).*f_exp(x);
                    
                    C_n(vector) = (1/T) *integral(f_integral,0,T);
                
                end
                
                % vector del eje x
                x   = linspace(Start,End);
                
                % Calculamos la sumatoria de 
                for i           = 1:length(x)
                
                    fx          = sum(C_n.*exp((1i*2*pi*x(i)*arms)/T));
                
                    suma(i)     = real(fx);
                
                end 
                          
                hold(app.UIAxes,'on');
                plot(app.UIAxes,x,suma);
                
            end              
           
        end

        % Button pushed function: Especturm_Button
        function Especturm_ButtonPushed(app, event)
            % Graficamos el espectrum en la grafica
            
            % Funcion de entrada 
            funcion = app.fx_EditField.Value;
            
            % Valores de integracion
            Start   = app.Stard_EditField.Value;
            End     = app.End_EditField.Value;
            
            % Estado del numero de armonicos a evaluar en la funcion
            N       = app.Harmonics_Spinner.Value;
            
            % Intervalo de integracion 
            T       = End - Start;
            
            %
            F_R     = str2func(sprintf('@(x)%s',funcion));
            
            % Vector del numoero de armonicos (formula en la ilustracion 4)
            arms     = -N:N;
            
            % Coeficientes de integracion, vector (forumla en la ilustraccion 3)
            C_n = zeros(size(arms));
            
            % calculamos el coeficiente de integracion (formula en la ilustracion 3)
            for vector      = 1:length(arms)
                    
                n           = arms(vector);
                    
                f_exp       = @(x) exp(-1i*2*pi*x*n/T);
                    
                f_integral  = @(x) F_R(x).*f_exp(x);
                    
                C_n(vector) = (1/T) *integral(f_integral,0,T);
                
            end
            
            cla(app.UIAxes);
            
            stem(app.UIAxes,arms,abs(C_n));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'F(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FourielSeriesPlot_Label = uilabel(app.UIFigure);
            app.FourielSeriesPlot_Label.Text = 'Fouriel Series Plot';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x) =';
            app.fx_EditField = uieditfield(app.UIFigure, 'text');
            app.fx_EditField.Value = 'sign(cos(2*pi*x))';
            app.PlotFx_Button = uibutton(app.UIFigure, 'push');
            app.PlotFx_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotFx_ButtonPushed, true);
            app.PlotFx_Button.Text = 'Plot F(x)';
            app.IntegrationInterval_Label = uilabel(app.UIFigure);
            app.IntegrationInterval_Label.Text = 'Integration Interval';
            app.StardEditFieldLabel = uilabel(app.UIFigure);
            app.StardEditFieldLabel.Text = 'Stard';
            app.Stard_EditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Text = 'End';
            app.End_EditField = uieditfield(app.UIFigure, 'numeric');
            app.End_EditField.Value = 1;
            app.Clear_Button = uibutton(app.UIFigure, 'push');
            app.Clear_Button.ButtonPushedFcn = createCallbackFcn(app, @Clear_ButtonPushed, true);
            app.Clear_Button.Text = {'Clear'; ''};
            app.Especturm_Button = uibutton(app.UIFigure, 'push');
            app.Especturm_Button.ButtonPushedFcn = createCallbackFcn(app, @Especturm_ButtonPushed, true);
            app.Especturm_Button.Text = 'Especturm';
            app.HarmonicsSpinnerLabel = uilabel(app.UIFigure);
            app.HarmonicsSpinnerLabel.Text = 'Harmonics';
            app.Harmonics_Spinner = uispinner(app.UIFigure);
            app.Harmonics_Spinner.Value = 15;
            app.AddPlot_Button = uibutton(app.UIFigure, 'push');
            app.AddPlot_Button.ButtonPushedFcn = createCallbackFcn(app, @AddPlot_ButtonPushed, true);
            app.AddPlot_Button.Text = 'Add Plot';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'Icono_Unicauca.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img', 'Gifs-Matlab.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Series_Fouriel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end